classdef appEmail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MessageLabel                matlab.ui.control.Label
        MessageTextArea             matlab.ui.control.TextArea
        CoEditFieldLabel            matlab.ui.control.Label
        CoEditField                 matlab.ui.control.EditField
        ToEditFieldLabel            matlab.ui.control.Label
        ToEditField                 matlab.ui.control.EditField
        ConsultDateDatePickerLabel  matlab.ui.control.Label
        ConsultDateDatePicker       matlab.ui.control.DatePicker
        SENDEMAILButton             matlab.ui.control.Button
        CCEditFieldLabel            matlab.ui.control.Label
        CCEditField                 matlab.ui.control.EditField
        SubjectDropDownLabel        matlab.ui.control.Label
        SubjectDropDown             matlab.ui.control.DropDown
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: SENDEMAILButton
        function SENDEMAILButtonPushed(app, event)
            setpref('Internet', 'SMTP_Server',   'smtp.gmail.com');
            setpref('Internet', 'SMTP_Username', 'maykonmeneghel@gmail.com');
            setpref('Internet', 'SMTP_Password', 'Meneghel_1990');
 
            props = java.lang.System.getProperties;
            props.setProperty('mail.smtp.auth',                'true');  % Note: 'true' as a string, not a logical value!
            props.setProperty('mail.smtp.starttls.enable',     'true');  % Note: 'true' as a string, not a logical value!
            props.setProperty('mail.smtp.socketFactory.port',  '465');   % Note: '465'  as a string, not a numeric value!
            props.setProperty('mail.smtp.socketFactory.class', 'javax.net.ssl.SSLSocketFactory');
            
            sendmail(app.ToEditField.Value, app.SubjectDropDown.Value, app.MessageTextArea.Value); 
        end

        % Value changed function: ConsultDateDatePicker
        function ConsultDateDatePickerValueChanged(app, event)
                                 
            value = app.ConsultDateDatePicker.Value;
            
            %app.MessageTextArea.Value = 'Caro Sr. '+fig1.dataProfile.Name+' '+dataProfile.Family_Name+', lembrando que a nossa consulta ficou agendada para '+ string(value)+'às __:__,caso não consiga comparecer na data marcada, por gentileza retornar esse e-mail imediatamente. Atenciosamente, Maykon Meneghel';
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MessageLabel = uilabel(app.UIFigure);
            app.MessageLabel.Text = 'Message:';
            app.MessageTextArea = uitextarea(app.UIFigure);
            app.CoEditFieldLabel = uilabel(app.UIFigure);
            app.CoEditFieldLabel.Text = 'Co:';
            app.CoEditField = uieditfield(app.UIFigure, 'text');
            app.ToEditFieldLabel = uilabel(app.UIFigure);
            app.ToEditFieldLabel.Text = 'To:';
            app.ToEditField = uieditfield(app.UIFigure, 'text');
            app.ConsultDateDatePickerLabel = uilabel(app.UIFigure);
            app.ConsultDateDatePickerLabel.Text = 'Consult Date:';
            app.ConsultDateDatePicker = uidatepicker(app.UIFigure);
            app.ConsultDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ConsultDateDatePickerValueChanged, true);
            app.SENDEMAILButton = uibutton(app.UIFigure, 'push');
            app.SENDEMAILButton.ButtonPushedFcn = createCallbackFcn(app, @SENDEMAILButtonPushed, true);
            app.SENDEMAILButton.Text = 'SEND EMAIL';
            app.CCEditFieldLabel = uilabel(app.UIFigure);
            app.CCEditFieldLabel.Text = 'CC:';
            app.CCEditField = uieditfield(app.UIFigure, 'text');
            app.SubjectDropDownLabel = uilabel(app.UIFigure);
            app.SubjectDropDownLabel.Text = 'Subject:';
            app.SubjectDropDown = uidropdown(app.UIFigure);
            app.SubjectDropDown.Items = {'Consulta Agendada', 'Reagendamento', 'Solicitação de Exames', 'Outros'};
            app.SubjectDropDown.Value = 'Consulta Agendada';
        end
    end

    methods (Access = public)

        % Construct app
        function app = appEmail

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end